classdef BLEchannels < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SaveDataButton            matlab.ui.control.Button
        StopButton                matlab.ui.control.StateButton
        StartButton               matlab.ui.control.StateButton
        MeasurementLabel          matlab.ui.control.Label
        DisconnectButton          matlab.ui.control.StateButton
        PowerthedeviceBlueblinkingindicatesBLEadvertising2Label  matlab.ui.control.Label
        ClearButton               matlab.ui.control.Button
        LoggerTextArea            matlab.ui.control.TextArea
        LoggerTextAreaLabel       matlab.ui.control.Label
        DeviceNameEditField       matlab.ui.control.EditField
        DeviceNameEditFieldLabel  matlab.ui.control.Label
        ConnectButton             matlab.ui.control.StateButton
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        device              % BLE device object
        commandCharObj      % BLE characteristic object
        dataCharObj         % BLE characteristic object
        packetCount = 0     % Count packets received
        RecordedData        % [N x 8] data
        numChannels         % ADS1298
        offsetStep          % Vertical offset per channel - adjust this
        plotWindow          % Description
        sampleRate          % Description
        lines               % Description
    end
    
    methods (Access = private)
        
        function connectDevice(app)
            name = string(app.DeviceNameEditField.Value);
            app.Logger(clear=true);

            % Scan for BLE devices
            devices = blelist;
            
            % Look for your device by name
            idx = find(devices.Name == name); % "TBI_Monitor"
            
            if isempty(idx)
                app.Logger(message='Device not found');
                app.toggleButton('BLE',true);
                app.toggleButton('BLE',false);
                return
            end
            
            % Connect to the device
            app.device = ble(devices.Address(idx(1)));
            message = 'Connected to: ' + app.device.Name;
            app.Logger(message=message);

            app.dataCharObj = characteristic(app.device, "1840", "FFF1");
            app.commandCharObj = characteristic(app.device, "1840", "FFF2");
            subscribe(app.dataCharObj, "notification");
            app.dataCharObj.DataAvailableFcn = @(src,event)app.handleData(src,event);
        end
        
        function Logger(app, args)
            arguments
                app
                args.message = ""
                args.clear = false
            end
            % Clear the log if requested
            if args.clear
                app.LoggerTextArea.Value = {''};
                return;
            end
            % Append message if provided
            if strlength(args.message) > 0
                currentText = app.LoggerTextArea.Value;
                newLine = char(string(args.message));  % ensure it's a char
                app.LoggerTextArea.Value = [currentText; {newLine}];
            end
        end
        
        function disconnectDevice(app)
            cmd = 'DISCONNECT';
            unsubscribe(app.dataCharObj);
            write(app.commandCharObj, uint8(cmd), "withoutResponse");
            message = 'Disconnected from: ' + app.device.Name;
            app.Logger(message=message);
            app.device = [];
        end
        
        function toggleButton(app,button,principle)
            if strcmp(button,'BLE')
                if principle
                    app.DisconnectButton.Value = ~app.DisconnectButton.Value;
                else
                    app.ConnectButton.Value = ~app.ConnectButton.Value;
                end
            elseif strcmp(button,'MEASURE')
                if principle
                    app.StopButton.Value = ~app.StartButton.Value;
                else
                    app.StartButton.Value = ~app.StopButton.Value;
                end
            end
        end
        
        function startData(app)
            app.RecordedData = [];  % Clear buffer
            cmd = 'START';
            write(app.commandCharObj, uint8(cmd), "withoutResponse");
            message = 'Starting Data stream';
            app.Logger(message=message);
        end
        
        function stopData(app)
            cmd = 'STOP';
            write(app.commandCharObj, uint8(cmd), "withoutResponse");
            message = 'Stopping Data stream';
            app.Logger(message=message);
        end
        
        
        function handleData(app,src, evt)
            [rawData, ~] = read(src, 'oldest');
            numSamples = 10;
            maxCounts = 2^23 - 1;
            VrefFullScale = 2.4;   % Adjust if needed

            decoded = zeros(numSamples, app.numChannels);
        
            for sampleIdx = 1:numSamples
                for ch = 1:app.numChannels
                    offset = (sampleIdx - 1) * 24 + (ch - 1) * 3;
                    b1 = rawData(offset + 1);
                    b2 = rawData(offset + 2);
                    b3 = rawData(offset + 3);
                    val = int32(bitor(bitshift(b1, 16), bitor(bitshift(b2, 8), b3)));
        
                    if bitget(val, 24)
                        val = bitor(val, int32(-2^24));  % sign extension
                    end
        
                    decoded(sampleIdx, ch) = double(val) / maxCounts * VrefFullScale;
                end
            end
        
            % Append raw data (no offset added here)
            app.RecordedData = [app.RecordedData; decoded];
        
            % Update plot using raw data and offsets
            app.updatePlotWithOffset();
        end


        function updatePlotWithOffset(app)
            if size(app.RecordedData, 1) < app.plotWindow
                dataToPlot = app.RecordedData;
            else
                dataToPlot = app.RecordedData(end - app.plotWindow + 1 : end, :);
            end
        
            plotData = dataToPlot';  % channels x samples
        
            % Apply vertical offset
            for ch = 1:app.numChannels
                plotData(ch, :) = plotData(ch, :) + ch * app.offsetStep;
            end
        
            % Time vector
            dt = 1 / app.sampleRate;
            timeVec = (-size(dataToPlot, 1) + 1 : 0) * dt;
        
            % Update lines
            for ch = 1:app.numChannels
                set(app.lines(ch), 'XData', timeVec, 'YData', plotData(ch, :));
            end
        
            % Update axis limits
            app.UIAxes.XLim = [timeVec(1), timeVec(end)];
            app.UIAxes.YLim = [app.offsetStep/2, app.numChannels * app.offsetStep+ app.offsetStep/2];
        
            drawnow limitrate;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ConnectButton.Value = false;
            app.DisconnectButton.Value = true;

            app.StartButton.Value = false;
            app.StopButton.Value = true;

            % Custom UI settings
            app.numChannels = 8;
            app.offsetStep = 4.8;        % Spacing between channels in volts
            app.plotWindow = 1000;        % Number of samples to show (adjust as needed)
            app.sampleRate = 500;        % Hz, based on your BLE transmission rate
            % Configure UIAxes
            app.UIAxes.YLim = [app.offsetStep/2, app.numChannels * app.offsetStep+ app.offsetStep/2];
            
            app.UIAxes.YLabel.String = 'Voltage (V)';
            app.UIAxes.XLabel.String = 'Time (s)';
            app.UIAxes.Title.String = 'Channel Data (1-8)';
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.UIAxes.YTick = ((0:app.numChannels - 1) + 1.0) * app.offsetStep;
            app.UIAxes.YTickLabel = arrayfun(@(ch) sprintf("CH %d", ch), 1:app.numChannels, 'UniformOutput', false);
            
            hold(app.UIAxes, 'on');
            app.lines = gobjects(app.numChannels, 1);  % Preallocate graphics handles
            for ch = 1:app.numChannels
                app.lines(ch) = plot(app.UIAxes, NaN, NaN, 'LineWidth', 1.5);
            end
            hold(app.UIAxes, 'off');

            disableDefaultInteractivity(app.UIAxes)
            app.UIAxes.Toolbar.Visible = 'off'; 
        end

        % Value changed function: ConnectButton, DeviceNameEditField
        function ConnectButtonValueChanged(app, event)
            value = app.ConnectButton.Value;
            if value
                app.toggleButton('BLE',value);
                app.connectDevice();
            else
                app.ConnectButton.Value = true;
            end
        end

        % Value changed function: DisconnectButton
        function DisconnectButtonValueChanged(app, event)
            value = app.DisconnectButton.Value;
            if value
                app.toggleButton('BLE',~value);
                app.disconnectDevice();
            else
                app.DisconnectButton.Value = true;
            end
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            if value
                app.startData();
                app.toggleButton('MEASURE',value);
            else
                app.StartButton.Value = true;
            end
        end

        % Value changed function: StopButton
        function StopButtonValueChanged(app, event)
            value = app.StopButton.Value;
            if value
                app.stopData();
                app.toggleButton('MEASURE',~value);
            else
                app.StopButton.Value = true;
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.Logger(clear=true);
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            % Open "Save As" dialog for .mat files
            [file, path] = uiputfile('*.mat', 'Save EMG Data As');
            
            % Check if user did NOT click Cancel
            if isequal(file, 0) || isequal(path, 0)
                % User cancelled, do nothing or log message
                app.Logger(message='Save cancelled by user.');
                return;
            end
            
            % Full file path
            fullFileName = fullfile(path, file);
            
            % Save the data
            tbiData = app.RecordedData;
            save(fullFileName, 'tbiData');
            
            % Log message
            message = 'Data saved in: '+ fullFileName;
            app.Logger(message=message);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                if isvalid(app.device)
                    app.disconnectDevice();
                end
            catch ME
                % Optionally log the error
                disp(ME.message);
            end
            delete(app); % Close the app
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Channel Data')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ConnectButton = uibutton(app.UIFigure, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.DeviceNameEditFieldLabel = uilabel(app.UIFigure);
            app.DeviceNameEditFieldLabel.Text = 'Device Name';
            app.DeviceNameEditField = uieditfield(app.UIFigure, 'text');
            app.DeviceNameEditField.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.DeviceNameEditField.Value = 'TBI_Monitor';
            app.LoggerTextAreaLabel = uilabel(app.UIFigure);
            app.LoggerTextAreaLabel.Text = 'Logger';
            app.LoggerTextArea = uitextarea(app.UIFigure);
            app.LoggerTextArea.Editable = 'off';
            app.LoggerTextArea.Value = {'Updates will be logged here.'};
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.PowerthedeviceBlueblinkingindicatesBLEadvertising2Label = uilabel(app.UIFigure);
            app.PowerthedeviceBlueblinkingindicatesBLEadvertising2Label.Text = {'1. Power the device. Blue blinking indicates BLE advertising.'; '2. Connect or disconnect the device. Green pulse when connected.'; '3. Start or stop the measurement'; '4. Save data in the form of a mat file'};
            app.DisconnectButton = uibutton(app.UIFigure, 'state');
            app.DisconnectButton.ValueChangedFcn = createCallbackFcn(app, @DisconnectButtonValueChanged, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.MeasurementLabel = uilabel(app.UIFigure);
            app.MeasurementLabel.Text = 'Measurement';
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonValueChanged, true);
            app.StopButton.Text = 'Stop';
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BLEchannels

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end